classdef Covid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResetButton                matlab.ui.control.Button
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoplotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathButton                matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        StateDropDown              matlab.ui.control.DropDown
        StateDropDownLabel         matlab.ui.control.Label
        CountryDropDown            matlab.ui.control.DropDown
        CountryDropDownLabel       matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)

        Global % Vector of objects of class WorldWideData (one object by country/state)
        Date % Row vector of dates
        countrySelected % Country chosen
        stateSelected % State chosen
        showCases % Plot cases
        showDeaths % Plot deaths
        showDaily % Plot daily cases/deaths
        averagingWindow % Moving mean window
        averageCases % Moving mean for cases
        averageDeaths % Moving mean for deaths
        
     end

    methods (Access = private)

        function plotData(app)

            cla(app.UIAxes,"reset");
            if app.showCases % CASES are always plot on the LEFT
                yyaxis(app.UIAxes,"left");                
                if isequal(app.stateSelected,"All") % Plotting when there is no specific state associated
                    for ii = 1:length(app.Global)
                        if strcmp(app.Global(ii).CountryName,app.countrySelected)
                            if app.averagingWindow > 1 % Plotting moving mean cases 
                                bar(app.UIAxes,app.Date,app.averageCases);                                
                                break
                            else
                                if app.showDaily % Plotting daily cases
                                    bar(app.UIAxes,app.Date,app.Global(ii).DailyCases);
                                    break
                                else % Plotting cumulative cases
                                    bar(app.UIAxes,app.Date,app.Global(ii).CumulativeCases);
                                    break
                                end
                            end
                        end
                    end
                else % Plotting with a specific state associated
                    for ii = 1:length(app.Global)
                        if strcmp(app.Global(ii).StateName,app.stateSelected)
                            if app.averagingWindow > 1 % Plotting moving mean cases
                                bar(app.UIAxes,app.Date,app.averageCases);
                                break
                            else
                                if app.showDaily % Plotting daily cases
                                    bar(app.UIAxes,app.Date,app.Global(ii).DailyCases);
                                    break
                                else % Plotting cumulative cases
                                    bar(app.UIAxes,app.Date,app.Global(ii).CumulativeCases);
                                    break
                                end
                            end
                        end
                    end
                end
                legend(app.UIAxes,"Number of Cases","Location",'northwest',"FontWeight","bold"); % Adding legend
                ytickformat(app.UIAxes, '%.0f');
                app.UIAxes.YAxis(1).Exponent = 0;
                if ~app.BothButton.Value % Clearing y-ticks on right side
                    yyaxis(app.UIAxes,"right");
                    set(app.UIAxes,'YTick',[])
                end
                hold(app.UIAxes,"on");
            end
            
            if app.showDeaths
                if app.BothButton.Value % In this case, DEATHS are plot on the RIGHT y-axis
                    yyaxis(app.UIAxes,"right");                    
                else % Here DEATHS are plot on the LEFT
                    yyaxis(app.UIAxes,"right");
                    set(app.UIAxes,'YTick',[]); % Clearing y-ticks on right side
                    yyaxis(app.UIAxes,"left");
                end
                if isequal(app.stateSelected,"All") % Plotting when there is no specific state associated
                    for ii = 1:length(app.Global)
                        if strcmp(app.Global(ii).CountryName,app.countrySelected)
                            if app.averagingWindow > 1 % Plotting moving mean deaths
                                plot(app.UIAxes,app.Date,app.averageDeaths,'r','LineWidth',1);
                                break
                            else
                                if app.showDaily % Plotting daily deaths
                                    plot(app.UIAxes,app.Date,app.Global(ii).DailyDeaths, 'r','LineWidth',1);
                                    break
                                else % Plotting cumulative deaths
                                    plot(app.UIAxes,app.Date,app.Global(ii).CumulativeDeaths,'r','LineWidth',1);
                                    break
                                end
                            end
                        end
                    end
                else % Plotting with a specific state associated
                    for ii = 1:length(app.Global)
                        if strcmp(app.Global(ii).StateName,app.stateSelected)
                            if app.averagingWindow > 1 % Plotting moving mean deaths
                                plot(app.UIAxes,app.Date,app.averageDeaths,'r','LineWidth',1);
                                break
                            else
                                if app.showDaily % Plotting daily deaths
                                    plot(app.UIAxes,app.Date,app.Global(ii).DailyDeaths,'r','LineWidth',1);
                                    break
                                else % Plotting cumulative deaths
                                    plot(app.UIAxes,app.Date,app.Global(ii).CumulativeDeaths,'r','LineWidth',1);
                                    break
                                end
                            end
                        end
                    end
                end
                if app.BothButton.Value % RIGHT y-axis 
                    ytickformat(app.UIAxes, '%.0f');
                    app.UIAxes.YAxis(2).Exponent = 0; 
                    app.UIAxes.YColor = 'red';                    
                    legend(app.UIAxes,["Number of Cases" "Number of Deaths"],"Location","northwest","FontWeight","bold");
                else % LEFT y-axis
                    ytickformat(app.UIAxes, '%.0f');
                    app.UIAxes.YAxis(1).Exponent = 0;
                    app.UIAxes.YColor = 'red';
                    legend(app.UIAxes,"Number of Deaths","Location","northwest","FontWeight","bold");                    
                end
            end            
            title(app.UIAxes,app.createTitle(),"FontSize",15) % Adding title through function createTitle
            grid(app.UIAxes,'on');
            hold(app.UIAxes,"off");

        end
        
        function ttl = createTitle(app) % Creating title according to options selects in the GUI
            
            if app.CumulativeButton.Value
                OptionToPlot = "Cumulative ";
            else
                OptionToPlot = "Daily ";
            end
            if app.CasesButton.Value
                DataToPlot = "Cases ";
            elseif app.DeathButton.Value
                DataToPlot = "Deaths ";
            else
                DataToPlot = "Cases and Deaths ";
            end
            if app.averagingWindow > 1
                AvgToPlot = strcat(" (" + num2str(app.averagingWindow) + "-day mean)");
            else
                AvgToPlot = "";
            end 
            
            if isequal(app.stateSelected,"All")
                if isequal(app.countrySelected,"Global")
                    ttl = (OptionToPlot + "Number of " + DataToPlot + "Globally" + AvgToPlot);
                else
                    ttl = (OptionToPlot + "Number of " + DataToPlot + "in " + app.countrySelected + AvgToPlot);
                end
            else
                ttl = (OptionToPlot + "Number of " + DataToPlot + "in " + app.stateSelected + AvgToPlot);
            end       

        end

        function updateState(app) % Updating the state list according to the country selected

            states = [];
            for ii = 1:length(app.Global) % Visiting all objects
                if app.Global(ii).StateName(strcmp(app.Global(ii).CountryName,app.countrySelected)) ~= ""
                    states = [states app.Global(ii).StateName(strcmp(app.Global(ii).CountryName,app.countrySelected))];
                end
            end

            if length(states) >= 1
                app.StateDropDown.Items = ["All"  states(1:end)]; % Items to display in state drop down
                app.stateSelected = "All";
            else
                app.StateDropDown.Items = "All";
                app.stateSelected = "All";
            end

        end
        
        function calculateMean(app) % Calculating the moving mean according to the chosen window

            if app.CasesButton.Value || app.BothButton.Value % Calculating moving mean for covid CASES
                for ii = 1:length(app.Global) % Visiting all objects until the 'break' command
                    % Condition to calculate the moving mean if the country chosen has 'All' states selected
                    if isequal(app.Global(ii).CountryName,app.countrySelected) && isequal(app.stateSelected,'All')
                        if app.DailyButton.Value % Moving mean for DAILY cases
                            app.averageCases = movmean(app.Global(ii).DailyCases, app.averagingWindow);
                            break % All break commands in this functions are used to avoid unnecessary iterations
                        else % Moving mean for CUMULATIVE cases
                            app.averageCases = movmean(app.Global(ii).CumulativeCases, app.averagingWindow);
                            break
                        end
                        % Condition to calculate the moving mean in case there is only ONE STATE selected
                    elseif isequal(app.Global(ii).CountryName,app.countrySelected) && isequal(app.Global(ii).StateName, app.stateSelected)
                        if app.DailyButton.Value % Moving mean for DAILY cases
                            app.averageCases = movmean(app.Global(ii).DailyCases, app.averagingWindow);
                            break
                        else % Moving mean for CUMULATIVE cases
                            app.averageCases = movmean(app.Global(ii).CumulativeCases, app.averagingWindow);
                            break
                        end
                    end
                end
            end
            if app.DeathButton.Value || app.BothButton.Value % Calculating moving mean for covid DEATHS
                for ii = 1:length(app.Global) % Visiting all objects until the 'break' command
                    % Condition to calculate the moving mean if the country chosen has 'All' states selected
                    if isequal(app.Global(ii).CountryName,app.countrySelected) && isequal(app.stateSelected,'All') 
                        if app.DailyButton.Value % Moving mean for DAILY deaths
                            app.averageDeaths = movmean(app.Global(ii).DailyDeaths, app.averagingWindow);
                            break
                        else % Moving mean for CUMULATIVE deaths
                            app.averageDeaths = movmean(app.Global(ii).CumulativeDeaths, app.averagingWindow);
                            break
                        end
                        % Condition to calculate the moving mean in case there is only ONE STATE selected
                    elseif isequal(app.Global(ii).CountryName,app.countrySelected) && isequal(app.Global(ii).StateName, app.stateSelected)
                        if app.DailyButton.Value % Moving mean for DAILY deaths
                            app.averageDeaths = movmean(app.Global(ii).DailyDeaths, app.averagingWindow);
                            break
                        else % Moving mean for CUMULATIVE deaths
                            app.averageDeaths = movmean(app.Global(ii).CumulativeDeaths, app.averagingWindow);
                            break
                        end
                    end
                end
            end
        end

        function calculateTotal(app) % Calculating the global amount for cumulative cases/deaths

            sumCases = 0; sumDeaths = 0;
            for jj = 1:length(app.Global(2).CumulativeCases)
                for ii = 2:length(app.Global)
                    if isequal(app.Global(ii).StateName,"")
                        sumCases = sumCases + app.Global(ii).CumulativeCases(jj);
                        sumDeaths = sumDeaths + app.Global(ii).CumulativeDeaths(jj);
                    end
                end
                % Editing properties in first object through "set method"
                app.Global(1).CumulativeCases(jj) = sumCases; % First object in the variable Global stores the cumulative cases/deaths
                app.Global(1).CumulativeDeaths(jj) = sumDeaths;
                sumCases = 0; sumDeaths = 0;
            end

        end


        function calculateDaily(app) % Calculating data for daily cases/deaths

            for ii = 1:length(app.Global) % Visiting all objects
                for jj = 2:length(app.Global(2).CumulativeCases) % First input is set to zero as there is no previous data
                    % Editing properties in each object through "set method"
                    app.Global(ii).DailyCases(jj) = app.Global(ii).CumulativeCases(jj) - app.Global(ii).CumulativeCases(jj-1);
                    app.Global(ii).DailyDeaths(jj) = app.Global(ii).CumulativeDeaths(jj) - app.Global(ii).CumulativeDeaths(jj-1);
                    % It is possible to have errors in data spreadsheet (e.g Human errors)
                    % If results of daily cases/deaths are negative, the entry is set to zero
                    if app.Global(ii).DailyCases(jj) < 0
                        app.Global(ii).DailyCases(jj) = 0;
                    end
                    if app.Global(ii).DailyDeaths(jj) < 0
                        app.Global(ii).DailyDeaths(jj) = 0;
                    end
                end
            end
        end
        
        function setDefaults(app)

            app.CountryDropDown.Value = "Global"; % Value in GUI
            app.countrySelected = "Global";
            app.StateDropDown.Items = "All";
            app.stateSelected = 'All';
            app.CasesButton.Value = true; % Value in GUI
            app.CumulativeButton.Value = true; % Value in GUI         
            app.averagingWindow = 1;
            app.AveragedofdaysSlider.Value = 1; % Value in GUI
            app.showCases = app.CasesButton.Value || app.BothButton.Value; % True at startup
            app.showDeaths = app.DeathButton.Value || app.BothButton.Value; % False at startup
            app.showDaily = false; 
            plotData(app)

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            load covid_data.mat covid_data; % Loading the data
            % Spliting the data into local variables
            countryName = covid_data(1:end,1);
            stateName = covid_data(1:end,2);
            CumCasesandDeaths = covid_data(2:end,3:end); % 2D array with cumulative number of cases and deaths for each country/state

            % Preallocation
            cases = zeros(338,707); deaths = zeros(338,707);
            data = repmat(WorldWideData("", "", 0, 0, 0, 0), 1, 339);
            for ii = 1:size(covid_data,1) % Creating an object of class WorldWideData for each country/state pair and allocating in a vector
                data(ii) = WorldWideData(countryName(ii),stateName(ii),[],[],[],[]); % Calling the constructor of class WorldWideData
            end
            for ii = 1:size(CumCasesandDeaths,1)  % Spliting and allocating CumCasesandDeaths 2D array in the corresponding object
                for jj = 1:size(CumCasesandDeaths,2)
                    cases(ii,jj) = CumCasesandDeaths{ii,jj}(1);
                    deaths(ii,jj) = CumCasesandDeaths{ii,jj}(2);
                end
                % Editing properties in each object through "set method"
                data(ii+1).CumulativeCases = cases(ii,1:end);
                data(ii+1).CumulativeDeaths = deaths(ii,1:end);
            end
            data(1).CountryName = "Global"; % "CumulativeCases" and "CumulativeDeaths" Globally are calculated in "calculateTotal" function
            data(1).StateName = "All";
            app.Global = data; % Copying the local row vector of objects (data) into an app variable
            app.Date = datetime(covid_data(1, 3:end)); % App variable for date (To be plotted in x-axis)
            app.CountryDropDown.Items = ['Global', unique(countryName(2:end)')]; % Items to display in country drop down         
            calculateTotal(app) % Called only once at startup
            calculateDaily(app) % Called only once at startup      
            setDefaults(app)

        end

        % Value changed function: CountryDropDown
        function CountryDropDownValueChanged(app, event)
            app.countrySelected = app.CountryDropDown.Value;
            updateState(app)
            if app.averagingWindow > 1
                calculateMean(app)
            end
            plotData(app)
        end

        % Value changed function: StateDropDown
        function StateDropDownValueChanged(app, event)
            app.stateSelected = app.StateDropDown.Value;
            if app.averagingWindow > 1
                calculateMean(app)
            end
            plotData(app)
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.showCases = app.CasesButton.Value || app.BothButton.Value;
            app.showDeaths = app.DeathButton.Value || app.BothButton.Value;
            if app.averagingWindow > 1
                calculateMean(app)
            end
            plotData(app)
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.showDaily = app.DailyButton.Value;
            if app.averagingWindow > 1
                calculateMean(app)
            end
            plotData(app)
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
          app.averagingWindow = round(event.Value);
          if app.averagingWindow > 1
              calculateMean(app)
          end
          plotData(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            setDefaults(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COVID-19 Data Visualization (Data Source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountryDropDownLabel = uilabel(app.UIFigure);
            app.CountryDropDownLabel.Text = 'Country';
            app.CountryDropDown = uidropdown(app.UIFigure);
            app.CountryDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryDropDownValueChanged, true);
            app.StateDropDownLabel = uilabel(app.UIFigure);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.UIFigure);
            app.StateDropDown.ValueChangedFcn = createCallbackFcn(app, @StateDropDownValueChanged, true);
            app.DatatoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathButton.Text = 'Death';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [2 4 6 8 10 12 14];
            app.AveragedofdaysSlider.Value = 1;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end